classdef appDesignerSolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        LoadDataButton          matlab.ui.control.Button
        StartSimulationButton   matlab.ui.control.Button
        StopSimulationButton    matlab.ui.control.Button
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        isStopPushed
    end
    
    methods (Access = private)
        
        function plotArena(app, agentPositions)

            app.UIAxes.ColorOrderIndex = 1;
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            % Points
            plot(app.UIAxes, agentPositions(:,1), agentPositions(:,2), 'o');    
            
            hold(app.UIAxes, 'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % When the load button is pushed, this function will be ran to load data from the .mat file provided
            
            % this line saves a variable called filename which is equal to the input from the File Name text box
            filename = app.FileNameEditField.Value;
            
            % this line loads the data from filename
            file = load(filename);
            
            % this line updates the data in the GUI table to equal the data from filename
            app.UITable.Data = file.agentPositions;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            % Main simulation loop
            app.isStopPushed = false;
            
            %% User Variable Initialization %%
            agentPositions = app.UITable.Data;
            set(app.UIAxes,'XLim',[0 1],'YLim',[0 1]);
            %% Simulation Loop %%
            while ~app.isStopPushed                
                % Updates the agents position
                agentPositions = agentPositions / 2;
                
                %% Plotting %%
                plotArena(app, agentPositions);
                drawnow;
                %% END Plotting
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arena')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.StopSimulationButton = uibutton(app.UIFigure, 'push');
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'agentPositions.mat';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X Position'; 'Y Position'};
            app.UITable.ColumnEditable = [true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesignerSolution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end